classdef Photo_Research < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StatusTab                       matlab.ui.container.Tab
        TimeoutsEditField               matlab.ui.control.NumericEditField
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        SoftwareversionTextArea         matlab.ui.control.TextArea
        SoftwareversionTextAreaLabel    matlab.ui.control.Label
        SerialnoTextArea                matlab.ui.control.TextArea
        SerialnoTextAreaLabel           matlab.ui.control.Label
        ModelTextArea                   matlab.ui.control.TextArea
        ModelTextAreaLabel              matlab.ui.control.Label
        SerialportDropDown              matlab.ui.control.DropDown
        SerialportDropDownLabel         matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        CheckstatusButton               matlab.ui.control.Button
        ClearerrorButton                matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.StateButton
        SetupTab                        matlab.ui.container.Tab
        CheckstatusButton_2             matlab.ui.control.Button
        ClearerrorButton_2              matlab.ui.control.Button
        StatusTextArea_2                matlab.ui.control.TextArea
        ApertureEditField               matlab.ui.control.EditField
        ApertureEditFieldLabel          matlab.ui.control.Label
        Addon3EditField                 matlab.ui.control.EditField
        Addon3EditFieldLabel            matlab.ui.control.Label
        Addon2EditField                 matlab.ui.control.EditField
        Addon2EditFieldLabel            matlab.ui.control.Label
        Addon1EditField                 matlab.ui.control.EditField
        Addon1EditFieldLabel            matlab.ui.control.Label
        PrimaryAccessoryEditField       matlab.ui.control.EditField
        PrimaryAccessoryEditFieldLabel  matlab.ui.control.Label
        MeasurementLabelEditField       matlab.ui.control.EditField
        MeasurementLabelEditFieldLabel  matlab.ui.control.Label
        ContrastEditField               matlab.ui.control.NumericEditField
        ContrastEditFieldLabel          matlab.ui.control.Label
        BacklightEditField              matlab.ui.control.NumericEditField
        BacklightEditFieldLabel         matlab.ui.control.Label
        ExposureTimemsEditField         matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel    matlab.ui.control.Label
        CyclestoAverageEditField        matlab.ui.control.NumericEditField
        CyclestoAverageEditFieldLabel   matlab.ui.control.Label
        SyncFrequencyHzEditField        matlab.ui.control.NumericEditField
        SyncFrequencyHzEditFieldLabel   matlab.ui.control.Label
        SyncModeDropDown                matlab.ui.control.DropDown
        SyncModeDropDownLabel           matlab.ui.control.Label
        SpeedModeDropDown               matlab.ui.control.DropDown
        SpeedModeDropDownLabel          matlab.ui.control.Label
        ShutterModeSwitch               matlab.ui.control.Switch
        ShutterModeSwitchLabel          matlab.ui.control.Label
        UnitSystemSwitch                matlab.ui.control.Switch
        UnitSystemSwitchLabel           matlab.ui.control.Label
        CIEObserverSwitch               matlab.ui.control.Switch
        CIEObserverSwitchLabel          matlab.ui.control.Label
        SensitivityModeSwitch           matlab.ui.control.Switch
        SensitivityModeSwitchLabel      matlab.ui.control.Label
        SmartDarkModeSwitch             matlab.ui.control.Switch
        SmartDarkModeSwitchLabel        matlab.ui.control.Label
        EchoModeSwitch                  matlab.ui.control.Switch
        EchoModeSwitchLabel             matlab.ui.control.Label
        MeasureTab                      matlab.ui.container.Tab
        RecallTab                       matlab.ui.container.Tab
        PlotTab                         matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: SerialportDropDown
        function SerialportDropDownOpening(app, event)
            app.SerialportDropDown.Items = cellstr(serialportlist("available"));
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            do_connect = app.ConnectButton.Value;
            port = app.SerialportDropDown.Value;
            if do_connect
                camera = initialize_camera(port,20);
                data = struct();
                data = send_command(camera,data,'M',111,[],[],[]);
                data = send_command(camera,data,'D',110,[],[],[]);
                data = send_command(camera,data,'D',114,[],[],[]);
                data = send_command(camera,data,'D',602,[],[],[]);
                app.ModelTextArea.Value = data.model;
                app.SerialnoTextArea.Value = data.serial_num;
                app.Softwareversion.Value = data.software_version;
                CheckstatusButtonPushed(app,event);
            elseif exist(camera,'var')
                send_command(camera,[],'Q',[],[],[],[]);
                clear camera;
            end
        end

        % Button pushed function: ClearerrorButton
        function ClearerrorButtonPushed(app, event)
            send_command(camera,[],'C',[],[],[],[]);
            CheckstatusButtonPushed(app,event);
        end

        % Button pushed function: CheckstatusButton
        function CheckstatusButtonPushed(app, event)
            status_msg = split(get_error_msg(send_command(camera,[],'I',[],[],[],[])),"\n");
            app.StatusTextArea.Value = "";
            for line = 1:length(status_msg)
                app.StatusTextArea.Value{line} = status_msg(line);
            end
        end

        % Value changed function: BacklightEditField
        function BacklightEditFieldValueChanged(app, event)
            value = app.BacklightEditField.Value;
            send_command(camera,[],'B',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: ContrastEditField
        function ContrastEditFieldValueChanged(app, event)
            value = app.ContrastEditField.Value;
            send_command(camera,[],'X',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: EchoModeSwitch
        function EchoModeSwitchValueChanged(app, event)
            send_command(camera,[],'E',[],[],[],[]);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: SmartDarkModeSwitch
        function SmartDarkModeSwitchValueChanged(app, event)
            value = app.SmartDarkModeSwitch.Value;
            send_command(camera,[],'SD',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: SensitivityModeSwitch
        function SensitivityModeSwitchValueChanged(app, event)
            value = app.SensitivityModeSwitch.Value;
            send_command(camera,[],'SH',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: CIEObserverSwitch
        function CIEObserverSwitchValueChanged(app, event)
            value = app.CIEObserverSwitch.Value;
            send_command(camera,[],'SO',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: UnitSystemSwitch
        function UnitSystemSwitchValueChanged(app, event)
            value = app.UnitSystemSwitch.Value;
            send_command(camera,[],'SU',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: ShutterModeSwitch
        function ShutterModeSwitchValueChanged(app, event)
            value = app.ShutterModeSwitch.Value;
            send_command(camera,[],'SZ',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: SpeedModeDropDown
        function SpeedModeDropDownValueChanged(app, event)
            value = app.SpeedModeDropDown.Value;
            send_command(camera,[],'SG',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: SyncModeDropDown
        function SyncModeDropDownValueChanged(app, event)
            value = app.SyncModeDropDown.Value;
            send_command(camera,[],'SS',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: ExposureTimemsEditField
        function ExposureTimemsEditFieldValueChanged(app, event)
            value = app.ExposureTimemsEditField.Value;
            send_command(camera,[],'SE',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: SyncFrequencyHzEditField
        function SyncFrequencyHzEditFieldValueChanged(app, event)
            value = app.SyncFrequencyHzEditField.Value;
            send_command(camera,[],'SK',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: CyclestoAverageEditField
        function CyclestoAverageEditFieldValueChanged(app, event)
            value = app.CyclestoAverageEditField.Value;
            send_command(camera,[],'SN',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: MeasurementLabelEditField
        function MeasurementLabelEditFieldValueChanged(app, event)
            value = app.MeasurementLabelEditField.Value;
            send_command(camera,[],'L',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: PrimaryAccessoryEditField
        function PrimaryAccessoryEditFieldValueChanged(app, event)
            value = app.PrimaryAccessoryEditField.Value;
            send_command(camera,[],'SP',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: ApertureEditField
        function ApertureEditFieldValueChanged(app, event)
            value = app.ApertureEditField.Value;
            send_command(camera,[],'SF',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: Addon1EditField
        function Addon1EditFieldValueChanged(app, event)
            value = app.Addon1EditField.Value;
            send_command(camera,[],'SA',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: Addon2EditField
        function Addon2EditFieldValueChanged(app, event)
            value = app.Addon2EditField.Value;
            send_command(camera,[],'SB',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Value changed function: Addon3EditField
        function Addon3EditFieldValueChanged(app, event)
            value = app.Addon3EditField.Value;
            send_command(camera,[],'SC',[],[],[],value);
            CheckstatusButtonPushed(app,event);
        end

        % Button pushed function: CheckstatusButton_2
        function CheckstatusButton_2Pushed(app, event)
            CheckstatusButtonPushed(app,event);
        end

        % Button pushed function: ClearerrorButton_2
        function ClearerrorButton_2Pushed(app, event)
            ClearerrorButtonPushed(app,event);
        end

        % Value changed function: StatusTextArea
        function StatusTextAreaValueChanged(app, event)
            value = app.StatusTextArea.Value;
            app.StatusTextArea_2.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StatusTab = uitab(app.TabGroup);
            app.StatusTab.Title = 'Status';
            app.ConnectButton = uibutton(app.StatusTab, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ClearerrorButton = uibutton(app.StatusTab, 'push');
            app.ClearerrorButton.ButtonPushedFcn = createCallbackFcn(app, @ClearerrorButtonPushed, true);
            app.ClearerrorButton.Text = 'Clear error';
            app.CheckstatusButton = uibutton(app.StatusTab, 'push');
            app.CheckstatusButton.ButtonPushedFcn = createCallbackFcn(app, @CheckstatusButtonPushed, true);
            app.CheckstatusButton.Text = 'Check status';
            app.StatusTextArea = uitextarea(app.StatusTab);
            app.StatusTextArea.ValueChangedFcn = createCallbackFcn(app, @StatusTextAreaValueChanged, true);
            app.SerialportDropDownLabel = uilabel(app.StatusTab);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.StatusTab);
            app.SerialportDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SerialportDropDownOpening, true);
            app.ModelTextAreaLabel = uilabel(app.StatusTab);
            app.ModelTextAreaLabel.Text = 'Model';
            app.ModelTextArea = uitextarea(app.StatusTab);
            app.SerialnoTextAreaLabel = uilabel(app.StatusTab);
            app.SerialnoTextAreaLabel.Text = 'Serial no.';
            app.SerialnoTextArea = uitextarea(app.StatusTab);
            app.SoftwareversionTextAreaLabel = uilabel(app.StatusTab);
            app.SoftwareversionTextAreaLabel.Text = 'Software version';
            app.SoftwareversionTextArea = uitextarea(app.StatusTab);
            app.TimeoutsEditFieldLabel = uilabel(app.StatusTab);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.TimeoutsEditField = uieditfield(app.StatusTab, 'numeric');
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.EchoModeSwitchLabel = uilabel(app.SetupTab);
            app.EchoModeSwitchLabel.Text = 'Echo Mode';
            app.EchoModeSwitch = uiswitch(app.SetupTab, 'slider');
            app.EchoModeSwitch.Items = {'Disabled', 'Enabled'};
            app.EchoModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EchoModeSwitchValueChanged, true);
            app.EchoModeSwitch.Value = 'Disabled';
            app.SmartDarkModeSwitchLabel = uilabel(app.SetupTab);
            app.SmartDarkModeSwitchLabel.Text = 'Smart Dark Mode';
            app.SmartDarkModeSwitch = uiswitch(app.SetupTab, 'slider');
            app.SmartDarkModeSwitch.Items = {'Disabled', 'Enabled'};
            app.SmartDarkModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SmartDarkModeSwitchValueChanged, true);
            app.SmartDarkModeSwitch.Value = 'Disabled';
            app.SensitivityModeSwitchLabel = uilabel(app.SetupTab);
            app.SensitivityModeSwitchLabel.Text = 'Sensitivity Mode';
            app.SensitivityModeSwitch = uiswitch(app.SetupTab, 'slider');
            app.SensitivityModeSwitch.Items = {'Disabled', 'Enabled'};
            app.SensitivityModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SensitivityModeSwitchValueChanged, true);
            app.SensitivityModeSwitch.Value = 'Disabled';
            app.CIEObserverSwitchLabel = uilabel(app.SetupTab);
            app.CIEObserverSwitchLabel.Text = 'CIE Observer';
            app.CIEObserverSwitch = uiswitch(app.SetupTab, 'slider');
            app.CIEObserverSwitch.Items = {'2 deg', '10 deg'};
            app.CIEObserverSwitch.ValueChangedFcn = createCallbackFcn(app, @CIEObserverSwitchValueChanged, true);
            app.CIEObserverSwitch.Value = '2 deg';
            app.UnitSystemSwitchLabel = uilabel(app.SetupTab);
            app.UnitSystemSwitchLabel.Text = 'Unit System';
            app.UnitSystemSwitch = uiswitch(app.SetupTab, 'slider');
            app.UnitSystemSwitch.Items = {'English', 'Metric'};
            app.UnitSystemSwitch.ValueChangedFcn = createCallbackFcn(app, @UnitSystemSwitchValueChanged, true);
            app.UnitSystemSwitch.Value = 'Metric';
            app.ShutterModeSwitchLabel = uilabel(app.SetupTab);
            app.ShutterModeSwitchLabel.Text = 'Shutter Mode';
            app.ShutterModeSwitch = uiswitch(app.SetupTab, 'slider');
            app.ShutterModeSwitch.Items = {'Close after measurement', 'Always open'};
            app.ShutterModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ShutterModeSwitchValueChanged, true);
            app.ShutterModeSwitch.Value = 'Close after measurement';
            app.SpeedModeDropDownLabel = uilabel(app.SetupTab);
            app.SpeedModeDropDownLabel.Text = 'Speed Mode';
            app.SpeedModeDropDown = uidropdown(app.SetupTab);
            app.SpeedModeDropDown.Items = {'Normal', '1X Fast', '2X Fast', '4X Fast'};
            app.SpeedModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeedModeDropDownValueChanged, true);
            app.SpeedModeDropDown.Value = 'Normal';
            app.SyncModeDropDownLabel = uilabel(app.SetupTab);
            app.SyncModeDropDownLabel.Text = 'Sync Mode';
            app.SyncModeDropDown = uidropdown(app.SetupTab);
            app.SyncModeDropDown.Items = {'No Sync', 'Auto Sync', 'User Frequency'};
            app.SyncModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SyncModeDropDownValueChanged, true);
            app.SyncModeDropDown.Value = 'No Sync';
            app.SyncFrequencyHzEditFieldLabel = uilabel(app.SetupTab);
            app.SyncFrequencyHzEditFieldLabel.Text = 'Sync Frequency (Hz)';
            app.SyncFrequencyHzEditField = uieditfield(app.SetupTab, 'numeric');
            app.SyncFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SyncFrequencyHzEditFieldValueChanged, true);
            app.CyclestoAverageEditFieldLabel = uilabel(app.SetupTab);
            app.CyclestoAverageEditFieldLabel.Text = 'Cycles to Average';
            app.CyclestoAverageEditField = uieditfield(app.SetupTab, 'numeric');
            app.CyclestoAverageEditField.ValueChangedFcn = createCallbackFcn(app, @CyclestoAverageEditFieldValueChanged, true);
            app.ExposureTimemsEditFieldLabel = uilabel(app.SetupTab);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.SetupTab, 'numeric');
            app.ExposureTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimemsEditFieldValueChanged, true);
            app.BacklightEditFieldLabel = uilabel(app.SetupTab);
            app.BacklightEditFieldLabel.Text = 'Backlight (%)';
            app.BacklightEditField = uieditfield(app.SetupTab, 'numeric');
            app.BacklightEditField.ValueChangedFcn = createCallbackFcn(app, @BacklightEditFieldValueChanged, true);
            app.ContrastEditFieldLabel = uilabel(app.SetupTab);
            app.ContrastEditFieldLabel.Text = 'Contrast (%)';
            app.ContrastEditField = uieditfield(app.SetupTab, 'numeric');
            app.ContrastEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastEditFieldValueChanged, true);
            app.MeasurementLabelEditFieldLabel = uilabel(app.SetupTab);
            app.MeasurementLabelEditFieldLabel.Text = 'Measurement Label';
            app.MeasurementLabelEditField = uieditfield(app.SetupTab, 'text');
            app.MeasurementLabelEditField.ValueChangedFcn = createCallbackFcn(app, @MeasurementLabelEditFieldValueChanged, true);
            app.PrimaryAccessoryEditFieldLabel = uilabel(app.SetupTab);
            app.PrimaryAccessoryEditFieldLabel.Text = 'Primary Accessory';
            app.PrimaryAccessoryEditField = uieditfield(app.SetupTab, 'text');
            app.PrimaryAccessoryEditField.ValueChangedFcn = createCallbackFcn(app, @PrimaryAccessoryEditFieldValueChanged, true);
            app.Addon1EditFieldLabel = uilabel(app.SetupTab);
            app.Addon1EditFieldLabel.Text = 'Addon 1';
            app.Addon1EditField = uieditfield(app.SetupTab, 'text');
            app.Addon1EditField.ValueChangedFcn = createCallbackFcn(app, @Addon1EditFieldValueChanged, true);
            app.Addon2EditFieldLabel = uilabel(app.SetupTab);
            app.Addon2EditFieldLabel.Text = 'Addon 2';
            app.Addon2EditField = uieditfield(app.SetupTab, 'text');
            app.Addon2EditField.ValueChangedFcn = createCallbackFcn(app, @Addon2EditFieldValueChanged, true);
            app.Addon3EditFieldLabel = uilabel(app.SetupTab);
            app.Addon3EditFieldLabel.Text = 'Addon 3';
            app.Addon3EditField = uieditfield(app.SetupTab, 'text');
            app.Addon3EditField.ValueChangedFcn = createCallbackFcn(app, @Addon3EditFieldValueChanged, true);
            app.ApertureEditFieldLabel = uilabel(app.SetupTab);
            app.ApertureEditFieldLabel.Text = 'Aperture';
            app.ApertureEditField = uieditfield(app.SetupTab, 'text');
            app.ApertureEditField.ValueChangedFcn = createCallbackFcn(app, @ApertureEditFieldValueChanged, true);
            app.StatusTextArea_2 = uitextarea(app.SetupTab);
            app.ClearerrorButton_2 = uibutton(app.SetupTab, 'push');
            app.ClearerrorButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearerrorButton_2Pushed, true);
            app.ClearerrorButton_2.Text = 'Clear error';
            app.CheckstatusButton_2 = uibutton(app.SetupTab, 'push');
            app.CheckstatusButton_2.ButtonPushedFcn = createCallbackFcn(app, @CheckstatusButton_2Pushed, true);
            app.CheckstatusButton_2.Text = 'Check status';
            app.MeasureTab = uitab(app.TabGroup);
            app.MeasureTab.Title = 'Measure';
            app.RecallTab = uitab(app.TabGroup);
            app.RecallTab.Title = 'Recall';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Photo_Research

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end