classdef app_SpectraScanPR655 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClearErrorButton                matlab.ui.control.Button
        CheckStatusButton               matlab.ui.control.Button
        StatusTextArea                  matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        TimeoutsEditField               matlab.ui.control.NumericEditField
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        BandwidthnmTextArea             matlab.ui.control.TextArea
        BandwidthnmTextAreaLabel        matlab.ui.control.Label
        RadiometryModeTextArea          matlab.ui.control.TextArea
        RadiometryModeTextAreaLabel     matlab.ui.control.Label
        PhotometryModeTextArea          matlab.ui.control.TextArea
        PhotometryModeTextAreaLabel     matlab.ui.control.Label
        BaudRateKbpsDropDown            matlab.ui.control.DropDown
        BaudRateKbpsDropDownLabel       matlab.ui.control.Label
        EchoModeSwitch                  matlab.ui.control.Switch
        EchoModeSwitchLabel             matlab.ui.control.Label
        UpdateSetupButton               matlab.ui.control.Button
        ContrastEditField               matlab.ui.control.NumericEditField
        ContrastEditFieldLabel          matlab.ui.control.Label
        BacklightEditField              matlab.ui.control.NumericEditField
        BacklightEditFieldLabel         matlab.ui.control.Label
        ExposureTimemsEditField         matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel    matlab.ui.control.Label
        CyclestoAverageEditField        matlab.ui.control.NumericEditField
        CyclestoAverageEditFieldLabel   matlab.ui.control.Label
        SyncFrequencyHzEditField        matlab.ui.control.NumericEditField
        SyncFrequencyHzEditFieldLabel   matlab.ui.control.Label
        SyncModeDropDown                matlab.ui.control.DropDown
        SyncModeDropDownLabel           matlab.ui.control.Label
        RemoteModeSwitch                matlab.ui.control.Switch
        RemoteModeSwitchLabel           matlab.ui.control.Label
        Addon3EditField                 matlab.ui.control.EditField
        Addon3EditFieldLabel            matlab.ui.control.Label
        Addon2EditField                 matlab.ui.control.EditField
        Addon2EditFieldLabel            matlab.ui.control.Label
        Addon1EditField                 matlab.ui.control.EditField
        Addon1EditFieldLabel            matlab.ui.control.Label
        PrimaryLensEditField            matlab.ui.control.EditField
        PrimaryLensEditFieldLabel       matlab.ui.control.Label
        MeasurementLabelEditField       matlab.ui.control.EditField
        MeasurementLabelEditFieldLabel  matlab.ui.control.Label
        ShutterModeSwitch               matlab.ui.control.Switch
        ShutterModeSwitchLabel          matlab.ui.control.Label
        UnitSystemSwitch                matlab.ui.control.Switch
        UnitSystemSwitchLabel           matlab.ui.control.Label
        CIEObserverSwitch               matlab.ui.control.Switch
        CIEObserverSwitchLabel          matlab.ui.control.Label
        SoftwareVersionTextArea         matlab.ui.control.TextArea
        SoftwareVersionTextAreaLabel    matlab.ui.control.Label
        SerialnoTextArea                matlab.ui.control.TextArea
        SerialnoTextAreaLabel           matlab.ui.control.Label
        ModelTextArea                   matlab.ui.control.TextArea
        ModelTextAreaLabel              matlab.ui.control.Label
        SerialPortDropDown              matlab.ui.control.DropDown
        SerialPortDropDownLabel         matlab.ui.control.Label
        MeasurementTab                  matlab.ui.container.Tab
        DataDropDown                    matlab.ui.control.DropDown
        DataDropDownLabel               matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        MeasureButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Camera % placeholder for SpectraScanPR655 class instance
    end
    
    methods (Access = private)
        
        function update_GUI(app)
            app.EchoModeSwitch.Value = app.Camera.echo_mode; % default
            app.BacklightEditField.Value = app.Camera.backlight; % default
            app.ContrastEditField.Value = app.Camera.contrast; % default
            app.StatusTextArea.Value = app.Camera.update_specs();
            app.ModelTextArea.Value = app.Camera.model;
            app.SerialnoTextArea.Value = app.Camera.serial_number;
            app.SoftwareVersionTextArea.Value = app.Camera.software_version;
            app.PrimaryLensEditField.Value = app.Camera.primary_lens;
            app.Addon1EditField.Value = app.Camera.addon1;
            app.Addon2EditField.Value = app.Camera.addon2;
            app.Addon3EditField.Value = app.Camera.addon3;
            app.SyncModeDropDown.Value = app.Camera.sync_mode;
            app.CyclestoAverageEditField.Value = app.Camera.cycles_to_average;
            app.ShutterModeSwitch.Value = app.Camera.shutter_mode;
            app.CIEObserverSwitch.Value = app.Camera.CIE_observer;
            app.UnitSystemSwitch.Value = app.Camera.unit_system;
            app.SyncFrequencyHzEditField.Value = app.Camera.sync_frequency;
            app.ExposureTimemsEditField.Value = app.Camera.exposure_time;
            app.BandwidthnmTextArea.Value = num2str(app.Camera.bandwidth);
            app.PhotometryModeTextArea.Value = app.Camera.photometry_mode;
            app.RadiometryModeTextArea.Value = app.Camera.radiometry_mode;
        end
        
        function clear_GUI(app)
            app.StatusTextArea.Value = '';
            app.ModelTextArea.Value = '';
            app.SerialnoTextArea.Value = '';
            app.SoftwareVersionTextArea.Value = '';
            app.PrimaryLensEditField.Value = '';
            app.Addon1EditField.Value = '';
            app.Addon2EditField.Value = '';
            app.Addon3EditField.Value = '';
            app.BandwidthnmTextArea.Value = '';
            app.PhotometryModeTextArea.Value = '';
            app.RadiometryModeTextArea.Value = '';
            app.BacklightEditField.Value = [];
            app.ContrastEditField.Value = [];
            app.CyclestoAverageEditField.Value = [];
            app.SyncFrequencyHzEditField.Value = [];
            app.ExposureTimemsEditField.Value = [];
            app.TimeoutsEditField.Value = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RemoteModeSwitch
        function RemoteModeSwitchValueChanged(app, event)
            if strcmpi(app.RemoteModeSwitch.Value, 'On')
                app.Camera = class_SpectraScanPR655(app.SerialPortDropDown.Value, str2double(app.BaudRateKbpsDropDown.Value)*1e3);
                if ~isempty(app.TimeoutsEditField.Value)
                    app.Camera.set_timeout(app.TimeoutsEditField.Value);
                else
                    app.TimeoutsEditField.Value = app.Camera.serial_device.Timeout;
                end
                app.StatusTextArea.Value = app.Camera.connect();
                app.update_GUI();
            elseif ~isempty(app.Camera)
                app.clear_GUI();
                app.Camera.disconnect();
                app.Camera = struct();
            end
        end

        % Drop down opening function: SerialPortDropDown
        function SerialPortDropDownOpening(app, event)
            app.SerialPortDropDown.Items = cellstr(serialportlist('available'));
        end

        % Button pushed function: UpdateSetupButton
        function UpdateSetupButtonPushed(app, event)
            if ~isempty(app.Camera)
                if ~isempty(app.TimeoutsEditField.Value)
                    app.Camera.set_timeout(app.TimeoutsEditField.Value);
                end
                if ~isempty(app.PrimaryLensEditField.Value)
                    app.Camera.select_primary_lens(app.PrimaryLensEditField.Value);
                end
                if ~isempty(app.Addon1EditField.Value)
                    app.Camera.select_addon1(app.Addon1EditField.Value);
                end
                if ~isempty(app.Addon2EditField.Value)
                    app.Camera.select_addon2(app.Addon2EditField.Value);
                end
                if ~isempty(app.Addon3EditField.Value)
                    app.Camera.select_addon3(app.Addon3EditField.Value);
                end
                if ~isempty(app.BacklightEditField.Value)
                    app.Camera.set_backlight(app.BacklightEditField.Value);
                end
                if ~isempty(app.ContrastEditField.Value)
                    app.Camera.set_contrast(app.ContrastEditField.Value);
                end
                app.Camera.select_sync_mode(app.SyncModeDropDown.Value);
                if strcmpi(app.SyncModeDropDown.Value, 'User') && ~isempty(app.SyncFrequencyHzEditField.Value)
                    app.Camera.set_sync_frequency(app.SyncFrequencyHzEditField.Value);
                end
                if ~isempty(app.ExposureTimemsEditField.Value)
                    app.Camera.set_exposure_time(app.ExposureTimemsEditField.Value);
                end
                if ~isempty(app.CyclestoAverageEditField.Value)
                    app.Camera.set_cycles_to_average(app.CyclestoAverageEditField.Value);
                end
                if ~isempty(app.MeasurementLabelEditField.Value)
                    app.Camera.set_measurement_label(app.MeasurementLabelEditField.Value);
                end
                app.Camera.select_shutter_mode(app.ShutterModeSwitch.Value);
                app.Camera.select_CIE_observer(app.CIEObserverSwitch.Value);
                app.Camera.select_unit_system(app.UnitSystemSwitch.Value);
                if ~strcmpi(app.Camera.echo_mode, app.EchoModeSwitch.Value)
                    app.Camera.toggle_echo_mode();
                end
                app.CheckStatusButtonPushed();
            end
        end

        % Button pushed function: CheckStatusButton
        function CheckStatusButtonPushed(app, event)
            if ~isempty(app.Camera)
                app.StatusTextArea.Value = app.Camera.update_status();
            end
        end

        % Button pushed function: ClearErrorButton
        function ClearErrorButtonPushed(app, event)
            if ~isempty(app.Camera)
                app.StatusTextArea.Value = app.Camera.clear_error();
            end
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            if ~isempty(app.Camera) && ~isempty(app.Camera.data)
                fig = [];
                ax = [];
                switch app.DataDropDown.Value
                    case 'Radiometric Spectrum'
                        [fig, ax] = app.Camera.plot_spectrum();
                    case 'CIE 1931 xy'
                        [fig, ax] = app.Camera.plot_CIE_1931();
                    case 'CIE 1960 uv'
                        [fig, ax] = app.Camera.plot_CIE_1960();
                    case "CIE 1976 u'v'"
                        [fig, ax] = app.Camera.plot_CIE_1976();
                    case 'Raw Light Signal'
                        [fig, ax] = app.Camera.plot_raw_light();
                    case 'Raw Dark Signal'
                        [fig, ax] = app.Camera.plot_raw_dark();
                end
                cla(app.UIAxes);
                if ~isempty(fig) && ~isempty(ax)
                    copyobj(ax.Children, app.UIAxes);
                    app.UIAxes.XLabel = ax.XLabel;
                    app.UIAxes.YLabel = ax.YLabel;
                    app.UIAxes.Title = ax.Title;
                    delete(fig);
                end
            end
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            app.StatusTextArea.Value = app.Camera.measure();
            app.update_GUI();
            
            app.DataDropDownValueChanged();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            save(app.Camera.measurement_label, 'app.Camera');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SerialPortDropDownLabel = uilabel(app.SetupTab);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.SetupTab);
            app.SerialPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortDropDownOpening, true);
            app.ModelTextAreaLabel = uilabel(app.SetupTab);
            app.ModelTextAreaLabel.Text = 'Model';
            app.ModelTextArea = uitextarea(app.SetupTab);
            app.SerialnoTextAreaLabel = uilabel(app.SetupTab);
            app.SerialnoTextAreaLabel.Text = 'Serial no.';
            app.SerialnoTextArea = uitextarea(app.SetupTab);
            app.SoftwareVersionTextAreaLabel = uilabel(app.SetupTab);
            app.SoftwareVersionTextAreaLabel.Text = 'Software Version';
            app.SoftwareVersionTextArea = uitextarea(app.SetupTab);
            app.CIEObserverSwitchLabel = uilabel(app.SetupTab);
            app.CIEObserverSwitchLabel.Text = 'CIE Observer';
            app.CIEObserverSwitch = uiswitch(app.SetupTab, 'slider');
            app.CIEObserverSwitch.Items = {'2 deg', '10 deg'};
            app.CIEObserverSwitch.Value = '2 deg';
            app.UnitSystemSwitchLabel = uilabel(app.SetupTab);
            app.UnitSystemSwitchLabel.Text = 'Unit System';
            app.UnitSystemSwitch = uiswitch(app.SetupTab, 'slider');
            app.UnitSystemSwitch.Items = {'Imperial', 'SI'};
            app.UnitSystemSwitch.Value = 'Imperial';
            app.ShutterModeSwitchLabel = uilabel(app.SetupTab);
            app.ShutterModeSwitchLabel.Text = 'Shutter Mode';
            app.ShutterModeSwitch = uiswitch(app.SetupTab, 'slider');
            app.ShutterModeSwitch.Items = {'Close after measurement', 'Always open'};
            app.ShutterModeSwitch.Value = 'Close after measurement';
            app.MeasurementLabelEditFieldLabel = uilabel(app.SetupTab);
            app.MeasurementLabelEditFieldLabel.Text = 'Measurement Label';
            app.MeasurementLabelEditField = uieditfield(app.SetupTab, 'text');
            app.MeasurementLabelEditField.CharacterLimits = [0 20];
            app.PrimaryLensEditFieldLabel = uilabel(app.SetupTab);
            app.PrimaryLensEditFieldLabel.Text = 'Primary Lens';
            app.PrimaryLensEditField = uieditfield(app.SetupTab, 'text');
            app.Addon1EditFieldLabel = uilabel(app.SetupTab);
            app.Addon1EditFieldLabel.Text = 'Addon 1';
            app.Addon1EditField = uieditfield(app.SetupTab, 'text');
            app.Addon2EditFieldLabel = uilabel(app.SetupTab);
            app.Addon2EditFieldLabel.Text = 'Addon 2';
            app.Addon2EditField = uieditfield(app.SetupTab, 'text');
            app.Addon3EditFieldLabel = uilabel(app.SetupTab);
            app.Addon3EditFieldLabel.Text = 'Addon 3';
            app.Addon3EditField = uieditfield(app.SetupTab, 'text');
            app.RemoteModeSwitchLabel = uilabel(app.SetupTab);
            app.RemoteModeSwitchLabel.Text = 'Remote Mode';
            app.RemoteModeSwitch = uiswitch(app.SetupTab, 'slider');
            app.RemoteModeSwitch.ValueChangedFcn = createCallbackFcn(app, @RemoteModeSwitchValueChanged, true);
            app.SyncModeDropDownLabel = uilabel(app.SetupTab);
            app.SyncModeDropDownLabel.Text = 'Sync Mode';
            app.SyncModeDropDown = uidropdown(app.SetupTab);
            app.SyncModeDropDown.Items = {'None', 'Automatic', 'User'};
            app.SyncModeDropDown.Value = 'Automatic';
            app.SyncFrequencyHzEditFieldLabel = uilabel(app.SetupTab);
            app.SyncFrequencyHzEditFieldLabel.Text = 'Sync Frequency (Hz)';
            app.SyncFrequencyHzEditField = uieditfield(app.SetupTab, 'numeric');
            app.SyncFrequencyHzEditField.AllowEmpty = 'on';
            app.SyncFrequencyHzEditField.Value = [];
            app.CyclestoAverageEditFieldLabel = uilabel(app.SetupTab);
            app.CyclestoAverageEditFieldLabel.Text = 'Cycles to Average';
            app.CyclestoAverageEditField = uieditfield(app.SetupTab, 'numeric');
            app.CyclestoAverageEditField.Limits = [1 99];
            app.CyclestoAverageEditField.RoundFractionalValues = 'on';
            app.CyclestoAverageEditField.AllowEmpty = 'on';
            app.CyclestoAverageEditField.Value = [];
            app.ExposureTimemsEditFieldLabel = uilabel(app.SetupTab);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.SetupTab, 'numeric');
            app.ExposureTimemsEditField.Limits = [6 6000];
            app.ExposureTimemsEditField.RoundFractionalValues = 'on';
            app.ExposureTimemsEditField.AllowEmpty = 'on';
            app.ExposureTimemsEditField.Value = [];
            app.BacklightEditFieldLabel = uilabel(app.SetupTab);
            app.BacklightEditFieldLabel.Text = 'Backlight (%)';
            app.BacklightEditField = uieditfield(app.SetupTab, 'numeric');
            app.BacklightEditField.Limits = [0 100];
            app.BacklightEditField.RoundFractionalValues = 'on';
            app.BacklightEditField.AllowEmpty = 'on';
            app.BacklightEditField.Value = [];
            app.ContrastEditFieldLabel = uilabel(app.SetupTab);
            app.ContrastEditFieldLabel.Text = 'Contrast (%)';
            app.ContrastEditField = uieditfield(app.SetupTab, 'numeric');
            app.ContrastEditField.Limits = [0 100];
            app.ContrastEditField.RoundFractionalValues = 'on';
            app.ContrastEditField.AllowEmpty = 'on';
            app.ContrastEditField.Value = [];
            app.UpdateSetupButton = uibutton(app.SetupTab, 'push');
            app.UpdateSetupButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSetupButtonPushed, true);
            app.UpdateSetupButton.Text = 'Update Setup';
            app.EchoModeSwitchLabel = uilabel(app.SetupTab);
            app.EchoModeSwitchLabel.Text = 'Echo Mode';
            app.EchoModeSwitch = uiswitch(app.SetupTab, 'slider');
            app.EchoModeSwitch.Items = {'Disabled', 'Enabled'};
            app.EchoModeSwitch.Value = 'Disabled';
            app.BaudRateKbpsDropDownLabel = uilabel(app.SetupTab);
            app.BaudRateKbpsDropDownLabel.Text = 'Baud Rate (Kbps)';
            app.BaudRateKbpsDropDown = uidropdown(app.SetupTab);
            app.BaudRateKbpsDropDown.Items = {'9.6', '19.2', '38.4', '57.6', '115.2'};
            app.BaudRateKbpsDropDown.Value = '115.2';
            app.PhotometryModeTextAreaLabel = uilabel(app.SetupTab);
            app.PhotometryModeTextAreaLabel.Text = 'Photometry Mode';
            app.PhotometryModeTextArea = uitextarea(app.SetupTab);
            app.RadiometryModeTextAreaLabel = uilabel(app.SetupTab);
            app.RadiometryModeTextAreaLabel.Text = 'Radiometry Mode';
            app.RadiometryModeTextArea = uitextarea(app.SetupTab);
            app.BandwidthnmTextAreaLabel = uilabel(app.SetupTab);
            app.BandwidthnmTextAreaLabel.Text = 'Bandwidth (nm)';
            app.BandwidthnmTextArea = uitextarea(app.SetupTab);
            app.TimeoutsEditFieldLabel = uilabel(app.SetupTab);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.TimeoutsEditField = uieditfield(app.SetupTab, 'numeric');
            app.TimeoutsEditField.Limits = [0 Inf];
            app.TimeoutsEditField.RoundFractionalValues = 'on';
            app.TimeoutsEditField.AllowEmpty = 'on';
            app.TimeoutsEditField.Value = [];
            app.MeasurementTab = uitab(app.TabGroup);
            app.MeasurementTab.Title = 'Measurement';
            app.UIAxes = uiaxes(app.MeasurementTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MeasureButton = uibutton(app.MeasurementTab, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.ExportButton = uibutton(app.MeasurementTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.DataDropDownLabel = uilabel(app.MeasurementTab);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.MeasurementTab);
            app.DataDropDown.Items = {'Radiometric Spectrum', 'CIE 1931 xy', 'CIE 1960 uv', 'CIE 1976 u''v''', 'Raw Light Signal', 'Raw Dark Signal'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = 'Radiometric Spectrum';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.CheckStatusButton = uibutton(app.UIFigure, 'push');
            app.CheckStatusButton.ButtonPushedFcn = createCallbackFcn(app, @CheckStatusButtonPushed, true);
            app.CheckStatusButton.Text = 'Check Status';
            app.ClearErrorButton = uibutton(app.UIFigure, 'push');
            app.ClearErrorButton.ButtonPushedFcn = createCallbackFcn(app, @ClearErrorButtonPushed, true);
            app.ClearErrorButton.Text = 'Clear Error';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_SpectraScanPR655

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end